classdef CarWashSimulatorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        WashSelectionPanel       matlab.ui.container.Panel
        FourthProgramDescLabel   matlab.ui.control.Label
        FourthProgramNameLabel   matlab.ui.control.Label
        FourthProgramPriceLabel  matlab.ui.control.Label
        FourthProgramButton      matlab.ui.control.Button
        ThirdProgramDescLabel    matlab.ui.control.Label
        ThirdProgramNameLabel    matlab.ui.control.Label
        ThirdProgramPriceLabel   matlab.ui.control.Label
        ThirdProgramButton       matlab.ui.control.Button
        SecondProgramDescLabel   matlab.ui.control.Label
        SecondProgramNameLabel   matlab.ui.control.Label
        SecondProgramPriceLabel  matlab.ui.control.Label
        SecondProgramButton      matlab.ui.control.Button
        FirstProgramDescLabel    matlab.ui.control.Label
        FirstProgramNameLabel    matlab.ui.control.Label
        FirstProgramPriceLabel   matlab.ui.control.Label
        FirstProgramButton       matlab.ui.control.Button
        ErrorMessageLabel        matlab.ui.control.Label
        ProceedPanel             matlab.ui.container.Panel
        Label_2                  matlab.ui.control.Label
        PROCEEDTOWASHLabel       matlab.ui.control.Label
        Image                    matlab.ui.control.Image
        ShinyMotorsLabel         matlab.ui.control.Label
        EnterCodePanel           matlab.ui.container.Panel
        EnteredCodeLabel         matlab.ui.control.Label
        CancelButton             matlab.ui.control.Button
        ClearButton              matlab.ui.control.Button
        Button_0                 matlab.ui.control.Button
        Button_9                 matlab.ui.control.Button
        Button_8                 matlab.ui.control.Button
        Button_7                 matlab.ui.control.Button
        Button_6                 matlab.ui.control.Button
        Button_5                 matlab.ui.control.Button
        Button_4                 matlab.ui.control.Button
        Button_2                 matlab.ui.control.Button
        Button_1                 matlab.ui.control.Button
        Button_3                 matlab.ui.control.Button
        SelectOperationLabel     matlab.ui.control.Label
        PayWithCardButton        matlab.ui.control.Button
        EnterCodeButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        chart % state chart object
        modelPath = addpath('Model\');
        modelName = 'CarWashSimulinkSimulator';
        ticketRepositoryAdapter;
        carWashAdapter;
        paymentAPIAdapter;
        cardReaderAdapter;
        myTimer;
        operationSelected;
        pinCode = '';
    end
    
    methods (Access = private)
        
        function showProceedPanel(app)
            app.ProceedPanel.Visible = 1;
            app.ProceedPanel.Enable = 'on';
            app.EnterCodePanel.Visible = 0;
            app.EnterCodePanel.Enable = 'off';
            app.SelectOperationLabel.Visible = 0;
            app.EnterCodeButton.Visible = 0;
            app.PayWithCardButton.Visible = 0;
            app.clearEnteredCodeLabel();
        end

        function showWashSelectionPanel(app)
            app.WashSelectionPanel.Visible = 1;
            app.WashSelectionPanel.Enable = 'on';
            app.EnterCodePanel.Visible = 0;
            app.EnterCodePanel.Enable = 'off';
            app.SelectOperationLabel.Visible = 0;
            app.EnterCodeButton.Visible = 0;
            app.PayWithCardButton.Visible = 0;
            app.ErrorMessageLabel.Visible = 0;
            app.ErrorMessageLabel.Text = '';
            app.clearEnteredCodeLabel();
        end

        function showStartScreen(app)
            app.ProceedPanel.Visible = 0;
            app.ProceedPanel.Enable = 'off';
            app.WashSelectionPanel.Visible = 0;
            app.WashSelectionPanel.Enable = 'off';
            app.EnterCodePanel.Visible = 0;
            app.EnterCodePanel.Enable = 'off';
            app.SelectOperationLabel.Visible = 1;
            app.EnterCodeButton.Visible = 1;
            app.PayWithCardButton.Visible = 1;
            app.ErrorMessageLabel.Visible = 0;
            app.ErrorMessageLabel.Text = '';
            app.clearEnteredCodeLabel();
        end

        function showErrorMessage(app, message)
            app.ErrorMessageLabel.Visible = 1;
            app.ErrorMessageLabel.Text = message;
            app.clearEnteredCodeLabel();
        end

        function clearEnteredCodeLabel(app)
            app.EnteredCodeLabel.Text = '';
            app.pinCode = '';
            stop(app.myTimer);
        end

        function setUpTimerProperty(app, timeoutPeriod)
            app.myTimer = timer('Period',timeoutPeriod,...
                'StartDelay',timeoutPeriod, ...
                'ExecutionMode', 'fixedSpacing', ...
                'TasksToExecute', Inf);
            app.myTimer.TimerFcn = @(~,~) app.showStartScreen();
        end
        
        function result = canStartWash(app, ticket)
            % Validate ticket code
            validationResult = TicketValidator.validate(ticket);
            if(~validationResult.IsValid)
                result.isValid = false;
                result.message = validationResult.Message;
                return;
            end

            % Instantiate correct validator
            vldtor = CarWashValidatorFactory.create(ticket.getWashType());

            % Validate Car Wash State
            wpStatus = app.carWashAdapter.getWaterPumpStatus();
            soapLevel = app.carWashAdapter.getSoapLevel();
            waxLevel = app.carWashAdapter.getWaxLevel();
            ultraShineLevel = app.carWashAdapter.getUltraShineLevel();
            carWashStateIsValid = vldtor.canStartWash(wpStatus,...
                soapLevel,waxLevel,ultraShineLevel);
            if(~carWashStateIsValid)
                result.isValid = false;
                result.message = 'Car wash cannot start now';
                return;
            end

            result.isValid = true;
            result.message = '';
        end
        
        function displayVirtualKeypad(app, operationSelected)
            if(strcmp(operationSelected, 'enterCode') == 1)
                panelTitle = 'Enter Code';
            else
                panelTitle = 'Enter your PIN';
            end
            app.EnterCodePanel.Title = panelTitle;
            app.EnterCodePanel.Visible = 1;
            app.EnterCodePanel.Enable = 'on';
            start(app.myTimer);
        end
        
        function handleEnterCodeMode(app, event)
           if(size(app.EnteredCodeLabel.Text) < 6)
               newLabel = strcat(app.EnteredCodeLabel.Text,event.Source.Text);
               app.EnteredCodeLabel.Text = newLabel;
               if(strlength(app.EnteredCodeLabel.Text) == 6)
                   code = app.EnteredCodeLabel.Text;
                   ticket = app.ticketRepositoryAdapter.getTicket(code);
                   % Pre-validate wash can start
                   validationResult = app.canStartWash(ticket);
                   if(~validationResult.isValid)
                       app.showErrorMessage(validationResult.message);
                   else
                        app.showProceedPanel();
                        result = app.carWashAdapter.startWash(...
                            ticket.getWashType());
                        if(~isempty(result))
                            app.showStartScreen();
                        end
                   end
               end
            end
        end

        function handleEnterPINMode(app, event)
            if(size(app.EnteredCodeLabel.Text) < 4)
                app.pinCode = strcat(app.pinCode,event.Source.Text);
                app.EnteredCodeLabel.Text = strcat(app.EnteredCodeLabel.Text,'*');
                if(strlength(app.EnteredCodeLabel.Text) == 4)
                    valid = app.paymentAPIAdapter.validate(app.pinCode);
                    if(valid == true)
                        app.showWashSelectionPanel();
                    else
                        app.showErrorMessage('Wrong PIN, enter again');
                    end
                end
            end
        end

        function setUpWashSelectionPanel(app)
            programs = CarWashProgramFactory.getAll();
            app.populateProgramRow(programs(1), app.FirstProgramNameLabel, ...
                app.FirstProgramDescLabel, app.FirstProgramPriceLabel);
            app.populateProgramRow(programs(2), app.SecondProgramNameLabel, ...
                app.SecondProgramDescLabel, app.SecondProgramPriceLabel);
            app.populateProgramRow(programs(3), app.ThirdProgramNameLabel, ...
                app.ThirdProgramDescLabel, app.ThirdProgramPriceLabel);
            app.populateProgramRow(programs(4), app.FourthProgramNameLabel, ...
                app.FourthProgramDescLabel, app.FourthProgramPriceLabel);
        end

        function populateProgramRow(app, washProgram, nameLbl, descLbl, priceLbl)
            app.assignValuesToLabel(washProgram.Description, ...
                descLbl);
            app.assignValuesToLabel(washProgram.Name, ...
                nameLbl);
            pLbl = strcat("€ ",num2str(washProgram.Price));
            app.assignValuesToLabel(pLbl, ...
                priceLbl);
        end

        function assignValuesToLabel(~, vl, lblControl)
            lblControl.Text = vl;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, config, carWashAdapter, ticketRepositoryAdapter)
            % CAR WASH ADAPTER
            if(isempty(carWashAdapter))
                app.carWashAdapter = CarWashAdapterFactory.create(config);
            else
                app.carWashAdapter = carWashAdapter;
            end
            % TICKET REPOSITORY ADAPTER
            if(isempty(ticketRepositoryAdapter))
                app.ticketRepositoryAdapter = TicketRepositoryAdapterFactory.create(config);
            else
                app.ticketRepositoryAdapter = ticketRepositoryAdapter;
            end
            % CARD READER ADAPTER
            if(config.isSimulation)
                app.cardReaderAdapter = TestCardReaderAdapter();
            else
                app.cardReaderAdapter = CardReaderAdapter();
            end
            % SCREEN TIMEOUT
            app.setUpTimerProperty(config.screenTimeoutPeriodSecs);    

            % SET UP WASH SELECTION PANEL
            app.setUpWashSelectionPanel();
        end

        % Button pushed function: EnterCodeButton, PayWithCardButton
        function operationButtonPushed(app, event)
            app.operationSelected = get(event.Source, 'Tag');
            if(strcmp(app.operationSelected, 'payWithCard') == 1)
                msgfig = msgbox('Please insert your card.','',"warn");
                card = app.cardReaderAdapter.readCard();
                close(msgfig);
                app.paymentAPIAdapter = PaymentAPIFactory.create(card.Number);
            end
            displayVirtualKeypad(app, app.operationSelected);
        end

        % Button pushed function: Button_0, Button_1, Button_2, Button_3, 
        % Button_4, Button_5, Button_6, Button_7, Button_8, Button_9
        function keyPushed(app, event)
            if(strcmp(app.operationSelected, 'enterCode') == 1)
                handleEnterCodeMode(app, event);
            else
                handleEnterPINMode(app, event);
            end
        end

        % Button pushed function: ClearButton
        function clearPushed(app, event)
            app.clearEnteredCodeLabel();
        end

        % Button pushed function: CancelButton
        function cancelPushed(app, event)
            app.showStartScreen();
        end

        % Button pushed function: FirstProgramButton, FourthProgramButton, 
        % SecondProgramButton, ThirdProgramButton
        function programButtonPushed(app, event)
            btnTag = get(event.Source, 'Tag');
            if(contains(btnTag, "first"))
                idx = 1;
            elseif (contains(btnTag, "second"))
                idx = 2;
            elseif (contains(btnTag, "third"))
                idx = 3;
            else
                idx = 4;
            end
            programs = CarWashProgramFactory.getAll();
            selectedProgram = programs(idx);
            app.paymentAPIAdapter.chargeAmount(selectedProgram.Price, Currency.EUR);
            app.showProceedPanel();
            result = app.carWashAdapter.startWash(selectedProgram.ProgramType);
            if(~isempty(result))
                app.showStartScreen();
            end
        end

        % Close request function: UIFigure
        function closeRequested(app, event)
            stop(app.myTimer);
            delete(app);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeRequested, true);
            app.EnterCodeButton = uibutton(app.UIFigure, 'push');
            app.EnterCodeButton.ButtonPushedFcn = createCallbackFcn(app, @operationButtonPushed, true);
            app.EnterCodeButton.Tag = 'enterCode';
            app.EnterCodeButton.Text = 'Enter Code';
            app.PayWithCardButton = uibutton(app.UIFigure, 'push');
            app.PayWithCardButton.ButtonPushedFcn = createCallbackFcn(app, @operationButtonPushed, true);
            app.PayWithCardButton.Tag = 'payWithCard';
            app.PayWithCardButton.Text = 'Pay with Card';
            app.SelectOperationLabel = uilabel(app.UIFigure);
            app.SelectOperationLabel.Text = 'Select Operation';
            app.EnterCodePanel = uipanel(app.UIFigure);
            app.EnterCodePanel.Enable = 'off';
            app.EnterCodePanel.Title = 'Enter Code';
            app.EnterCodePanel.Visible = 'off';
            app.EnterCodePanel.Tag = 'EnterCodePanel';
            app.Button_3 = uibutton(app.EnterCodePanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @keyPushed, true);
            app.Button_3.Text = '3';
            app.Button_1 = uibutton(app.EnterCodePanel, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @keyPushed, true);
            app.Button_1.Text = '1';
            app.Button_2 = uibutton(app.EnterCodePanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @keyPushed, true);
            app.Button_2.Text = '2';
            app.Button_4 = uibutton(app.EnterCodePanel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @keyPushed, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.EnterCodePanel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @keyPushed, true);
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.EnterCodePanel, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @keyPushed, true);
            app.Button_6.Text = '6';
            app.Button_7 = uibutton(app.EnterCodePanel, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @keyPushed, true);
            app.Button_7.Text = '7';
            app.Button_8 = uibutton(app.EnterCodePanel, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @keyPushed, true);
            app.Button_8.Text = '8';
            app.Button_9 = uibutton(app.EnterCodePanel, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @keyPushed, true);
            app.Button_9.Text = '9';
            app.Button_0 = uibutton(app.EnterCodePanel, 'push');
            app.Button_0.ButtonPushedFcn = createCallbackFcn(app, @keyPushed, true);
            app.Button_0.Text = '0';
            app.ClearButton = uibutton(app.EnterCodePanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @clearPushed, true);
            app.ClearButton.Text = 'CLEAR';
            app.CancelButton = uibutton(app.EnterCodePanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelPushed, true);
            app.CancelButton.Text = 'CANCEL';
            app.EnteredCodeLabel = uilabel(app.EnterCodePanel);
            app.EnteredCodeLabel.Tag = 'EnteredCodeLabel';
            app.EnteredCodeLabel.Text = '';
            app.ShinyMotorsLabel = uilabel(app.UIFigure);
            app.ShinyMotorsLabel.Text = 'ShinyMotors';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'C:\Users\Daniel Hernandez\Documents\MATLAB\ShinyMotors\Assets\Images\logo.png';
            app.ProceedPanel = uipanel(app.UIFigure);
            app.ProceedPanel.Enable = 'off';
            app.ProceedPanel.Visible = 'off';
            app.ProceedPanel.Tag = 'ProceedPanel';
            app.PROCEEDTOWASHLabel = uilabel(app.ProceedPanel);
            app.PROCEEDTOWASHLabel.Text = 'PROCEED TO WASH';
            app.Label_2 = uilabel(app.ProceedPanel);
            app.Label_2.Text = '<';
            app.ErrorMessageLabel = uilabel(app.UIFigure);
            app.ErrorMessageLabel.Visible = 'off';
            app.ErrorMessageLabel.Text = '';
            app.WashSelectionPanel = uipanel(app.UIFigure);
            app.WashSelectionPanel.Enable = 'off';
            app.WashSelectionPanel.Title = 'Select your program';
            app.WashSelectionPanel.Visible = 'off';
            app.FirstProgramButton = uibutton(app.WashSelectionPanel, 'push');
            app.FirstProgramButton.ButtonPushedFcn = createCallbackFcn(app, @programButtonPushed, true);
            app.FirstProgramButton.Tag = 'firstProgramButton';
            app.FirstProgramButton.Text = '✓';
            app.FirstProgramPriceLabel = uilabel(app.WashSelectionPanel);
            app.FirstProgramPriceLabel.Text = '€ 7';
            app.FirstProgramNameLabel = uilabel(app.WashSelectionPanel);
            app.FirstProgramNameLabel.Text = 'QUICK';
            app.FirstProgramDescLabel = uilabel(app.WashSelectionPanel);
            app.FirstProgramDescLabel.Text = 'Water spray';
            app.SecondProgramButton = uibutton(app.WashSelectionPanel, 'push');
            app.SecondProgramButton.ButtonPushedFcn = createCallbackFcn(app, @programButtonPushed, true);
            app.SecondProgramButton.Tag = 'secondProgramButton';
            app.SecondProgramButton.Text = '✓';
            app.SecondProgramPriceLabel = uilabel(app.WashSelectionPanel);
            app.SecondProgramPriceLabel.Text = '';
            app.SecondProgramNameLabel = uilabel(app.WashSelectionPanel);
            app.SecondProgramNameLabel.Text = '';
            app.SecondProgramDescLabel = uilabel(app.WashSelectionPanel);
            app.SecondProgramDescLabel.Text = '';
            app.ThirdProgramButton = uibutton(app.WashSelectionPanel, 'push');
            app.ThirdProgramButton.ButtonPushedFcn = createCallbackFcn(app, @programButtonPushed, true);
            app.ThirdProgramButton.Tag = 'thirdProgramButton';
            app.ThirdProgramButton.Text = '✓';
            app.ThirdProgramPriceLabel = uilabel(app.WashSelectionPanel);
            app.ThirdProgramPriceLabel.Text = '';
            app.ThirdProgramNameLabel = uilabel(app.WashSelectionPanel);
            app.ThirdProgramNameLabel.Text = '';
            app.ThirdProgramDescLabel = uilabel(app.WashSelectionPanel);
            app.ThirdProgramDescLabel.Text = '';
            app.FourthProgramButton = uibutton(app.WashSelectionPanel, 'push');
            app.FourthProgramButton.ButtonPushedFcn = createCallbackFcn(app, @programButtonPushed, true);
            app.FourthProgramButton.Tag = 'fourthProgramButton';
            app.FourthProgramButton.Text = '✓';
            app.FourthProgramPriceLabel = uilabel(app.WashSelectionPanel);
            app.FourthProgramPriceLabel.Text = '';
            app.FourthProgramNameLabel = uilabel(app.WashSelectionPanel);
            app.FourthProgramNameLabel.Text = '';
            app.FourthProgramDescLabel = uilabel(app.WashSelectionPanel);
            app.FourthProgramDescLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CarWashSimulatorApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end